classdef LoadGRUPY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DeleteoldrangesCheckBox     matlab.ui.control.CheckBox
        CancelButton                matlab.ui.control.Button
        OkButton                    matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        GroupingSelectionTab        matlab.ui.container.Tab
        DeactivateButton            matlab.ui.control.Button
        ActivateButton              matlab.ui.control.Button
        UIGroupingSelection         matlab.ui.control.Table
        PreviewTab                  matlab.ui.container.Tab
        SecondsorDateDropDown       matlab.ui.control.DropDown
        SecondsorDateDropDownLabel  matlab.ui.control.Label
        OffsettEditField            matlab.ui.control.NumericEditField
        OffsetinsEditFieldLabel     matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        FilenameField               matlab.ui.control.EditField
        LoadButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Time = [] % Description
        Offset = 0 % Description
        Date = false % Description
        Selection = [] % Description
        Captions = [] % Description
        Data = [] % Description
        UIGroupingSelectedRow % Description
        Output = [] % Description
    end
    
    methods (Access = private)
        function UpdateTable(app)
            if isempty(app.Data)
                return
            end
            if app.Date
                t = table();
                t.t1 = datetime(app.Time(:,1) + app.Offset, 'ConvertFrom', 'posixtime'); 
                t.t2 = datetime(app.Time(:,2) + app.Offset, 'ConvertFrom', 'posixtime'); 
                app.UITable.ColumnWidth = {140, 140};
            else
                t = table();
                t.t1 = seconds(app.Time(:,1) + app.Offset); 
                t.t2 = seconds(app.Time(:,2) + app.Offset); 
                app.UITable.ColumnWidth = 'auto';
            end
            for i=1:size(app.Data, 2)
                if app.Selection(i)
                    t.(char("s"+num2str(i))) = app.Data(:,i);
                end
            end
            app.UITable.Data = t;
            app.UITable.ColumnName = ['begin time in s', 'end time in s', app.Captions(app.Selection)'];
        end
        
        function LoadCompiledData(app, filename)
            try
                states = h5read(filename,'/statesequence');
                hw_setpoints = h5read(filename,'/hardware_setpoints');
                info = h5info(filename,'/statesequence');
            catch
                states = h5read(filename,'/compiled_sequence_data/statesequence');
                hw_setpoints = h5read(filename,'/compiled_sequence_data/hardware_setpoints');
                info = h5info(filename,'/compiled_sequence_data/statesequence');
            end
            names = fieldnames(states);
            hw_names = fieldnames(hw_setpoints);
            states_mat = [];
            states_names = {};
            for i=1:length(names)
                if strcmp(names{i},'time')
                    continue
                end
                if strcmp(names{i},'state')
                    continue
                end
%                 if any(strcmp(hw_names,names{i}))
%                     states.(names{i}) = hw_setpoints.(names{i});
%                 end
                states_mat = [states_mat; states.(names{i})];
                states_names = [states_names ,info.Datatype.Type.Member(i).Name];
            end
            states_mat = states_mat(:,1:end-1)';
            app.Data = states_mat;
            app.Offset = h5readatt(filename,'/','start_time');
            app.Time = double([states.time(1:end-1)', states.time(2:end)'])/1000;            
            app.Captions = states_names';
            app.Selection = true(size(app.Captions));
            
            app.UpdateTable()

            t = table();
            t.captions = app.Captions;
            t.selected = app.Selection;
            app.UIGroupingSelection.Data = t;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, output)
            app.Output = output;    
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile({'*.h5','HDF5 file'},'Choose grupy file');
            if file == 0
                return
            end
            % TODO: workaround: de.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            filename = fullfile(path, file);
            app.FilenameField.Value = filename;
            app.LoadCompiledData(filename)
        end

        % Value changed function: OffsettEditField
        function OffsettEditFieldValueChanged(app, event)
            app.Offset = app.OffsettEditField.Value;
            app.UpdateTable()
        end

        % Value changed function: SecondsorDateDropDown
        function SecondsorDateDropDownValueChanged(app, event)
            value = app.SecondsorDateDropDown.Value;
            
            if strcmp(value, 'Seconds')
                app.Date = false;
            else
                app.Date = true;
            end
            app.UpdateTable()
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab.Title == "Preview"
                disp("Preview")
                app.UpdateTable()
            end
        end

        % Cell edit callback: UIGroupingSelection
        function UIGroupingSelectionCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) == 2
                app.Selection(indices(1)) = newData;   
            end
        end

        % Cell selection callback: UIGroupingSelection
        function UIGroupingSelectionCellSelection(app, event)
            app.UIGroupingSelectedRow = event.Indices(:,1);            
        end

        % Button pushed function: ActivateButton
        function ActivateButtonPushed(app, event)
            app.Selection(app.UIGroupingSelectedRow) = true;
            app.UIGroupingSelection.Data.selected = app.Selection;
        end

        % Button pushed function: DeactivateButton
        function DeactivateButtonPushed(app, event)
            app.Selection(app.UIGroupingSelectedRow) = false;
            app.UIGroupingSelection.Data.selected = app.Selection;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            output = struct();
            output.captions = app.Captions(app.Selection);
            output.data = app.Data(:, app.Selection);
            output.time = app.Time + app.Offset;
            output.deleteOldRanges = app.DeleteoldrangesCheckBox.Value;
            app.Output.data = output;
            app.delete()    
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.delete()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FilenameField = uieditfield(app.UIFigure, 'text');
            app.FilenameField.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GroupingSelectionTab = uitab(app.TabGroup);
            app.GroupingSelectionTab.Title = 'Grouping Selection';
            app.UIGroupingSelection = uitable(app.GroupingSelectionTab);
            app.UIGroupingSelection.ColumnName = {'signals'; 'import as grouping'};
            app.UIGroupingSelection.RowName = {};
            app.UIGroupingSelection.ColumnEditable = [false true];
            app.UIGroupingSelection.CellEditCallback = createCallbackFcn(app, @UIGroupingSelectionCellEdit, true);
            app.UIGroupingSelection.CellSelectionCallback = createCallbackFcn(app, @UIGroupingSelectionCellSelection, true);
            app.ActivateButton = uibutton(app.GroupingSelectionTab, 'push');
            app.ActivateButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateButtonPushed, true);
            app.ActivateButton.Text = 'Activate';
            app.DeactivateButton = uibutton(app.GroupingSelectionTab, 'push');
            app.DeactivateButton.ButtonPushedFcn = createCallbackFcn(app, @DeactivateButtonPushed, true);
            app.DeactivateButton.Text = 'Deactivate';
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.UITable = uitable(app.PreviewTab);
            app.UITable.ColumnName = {'time begin in s'; 'time end in s'};
            app.UITable.RowName = {};
            app.OffsetinsEditFieldLabel = uilabel(app.PreviewTab);
            app.OffsetinsEditFieldLabel.Text = 'Offset in s';
            app.OffsettEditField = uieditfield(app.PreviewTab, 'numeric');
            app.OffsettEditField.ValueChangedFcn = createCallbackFcn(app, @OffsettEditFieldValueChanged, true);
            app.SecondsorDateDropDownLabel = uilabel(app.PreviewTab);
            app.SecondsorDateDropDownLabel.Text = 'Seconds or Date';
            app.SecondsorDateDropDown = uidropdown(app.PreviewTab);
            app.SecondsorDateDropDown.Items = {'Seconds', 'Date'};
            app.SecondsorDateDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondsorDateDropDownValueChanged, true);
            app.SecondsorDateDropDown.Value = 'Seconds';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DeleteoldrangesCheckBox = uicheckbox(app.UIFigure);
            app.DeleteoldrangesCheckBox.Text = 'Delete old ranges';
            app.DeleteoldrangesCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadGRUPY(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end